classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CorreccinDistanciaButton  matlab.ui.control.Button
        ClculodeazimutButton      matlab.ui.control.Button
        ConvertirPTLGeodButton    matlab.ui.control.Button
        ConversindecoordenadasGeodsicasPTLLabel  matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        ConvertirGeoPTLButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertirGeoPTLButton
        function ConvertirGeoPTLButtonPushed(app, event)
              
                 try
                % Permitir al usuario seleccionar un archivo
                [fileName, filePath] = uigetfile({'*.txt;*.csv', 'Text and CSV files (*.txt, *.csv)'}, 'Select a file');
                if isequal(fileName, 0)
                    disp('File selection canceled.');
                    return;
                end
                filePath = fullfile(filePath, fileName);

                % Leer datos del archivo
                data = app.leerArchivo(filePath);

                % Conexión a la base de datos
                conn = database('mysql1', 'alexs', 'localhost');
                if isopen(conn)
                    % Vaciar la tabla antes de insertar nuevos datos
                    exec(conn, 'DELETE FROM coordenadas');

                    % Almacenar los datos en la base de datos
                    app.insertData(conn, data);

                    % Leer datos de la base de datos
                    data = fetch(conn, ['SELECT latitud, longitud, altura ' ...
                        'FROM coordenadas']);
                    close(conn);

                    % Extraer coordenadas
                    latitudes = data.latitud;
                    longitudes = data.longitud;
                    altura = data.altura;

                    MeridianoC = -70; % Meridiano central

                    % Parámetros PTL
                    R = 6378000; % Radio medio
                    hptl = 600; % Altura plano PTL
                    falsoN = 7000000; % Falso Norte
                    falsoE = 200000;  % Falso Este
                    Factoresc = (R + hptl) / R; % Factor escala

                    % Parámetros elipsoide (WGS84)
                    a = 6378137.0; % Eje semi mayor
                    f = 1 / 298.257223563; % Achatamiento
                    e2 = 2 * f - f^2; % Excentricidad al cuadrado

                    % Convertir el meridiano central a radianes
                    MeridianoC = deg2rad(MeridianoC);

                    % Crear matrices para almacenar los resultados
                    N = zeros(size(latitudes));
                    E = zeros(size(longitudes));

                    % Convertir cada par de coordenadas
                    for i = 1:length(latitudes)
                        lat = latitudes(i);
                        lon = longitudes(i);

                        % Convertir latitud y longitud a radianes
                        lat = deg2rad(lat);
                        lon = deg2rad(lon);

                        % Diferencia de longitud con el meridiano central
                        deltaLambda = lon - MeridianoC;

                        % Calcular parámetros necesarios
                        N1 = a / sqrt(1 - e2 * sin(lat)^2);
                        T = tan(lat)^2;
                        C = e2 * cos(lat)^2 / (1 - e2);
                        A = deltaLambda * cos(lat);

                        % Calcular coordenadas Norte (N) y Este (E)
                        M = a * ((1 - e2 / 4 - 3 * e2^2 / 64 - 5 * e2^3 / 256) * lat ...
                               - (3 * e2 / 8 + 3 * e2^2 / 32 + 45 * e2^3 / 1024) * sin(2 * lat) ...
                               + (15 * e2^2 / 256 + 45 * e2^3 / 1024) * sin(4 * lat) ...
                               - (35 * e2^3 / 3072) * sin(6 * lat));

                        N(i) = falsoN + Factoresc * (M + N1 * tan(lat) * (A^2 / 2 + ...
                            (5 - T + 9 * C + 4 * C^2) * A^4 / 24 + (61 - 58 * T + T^2 + ...
                            600 * C - 330 * e2) * A^6 / 720));
                        E(i) = falsoE + Factoresc * N1 * (A + (1 - T + C) * A^3 / 6 + ...
                            (5 - 18 * T + T^2 + 72 * C - 58 * e2) * A^5 / 120);
                    end

                    % Actualizar la tabla con los resultados
                    app.UITable.Data = [N, E, altura];
                    app.UITable.ColumnName = {'Norte', 'Este', 'Altura'};
                    app.UITable.ColumnFormat = {'bank', 'bank', 'bank'};

                    % Mostrar mensaje de éxito
                    uialert(app.UIFigure, 'Conversión completada. Los resultados se han actualizado en la tabla.', 'Éxito', 'Icon', 'success');
                end
            catch ME
                % Mostrar mensaje de error si ocurre una excepción
                uialert(app.UIFigure, ['Error: ' ME.message], 'Error', 'Icon', 'error');
                 end
        end
    
        function data = leerArchivo(~, filePath)
            [~, ~, ext] = fileparts(filePath);
            if strcmp(ext, '.txt')
                data = readtable(filePath, 'Delimiter', ' ', 'Format', '%f%f%f', 'ReadVariableNames', false);
            elseif strcmp(ext, '.csv')
                data = readtable(filePath, 'Format', '%f%f%f', 'ReadVariableNames', false);
            else
                error('Unsupported file format.');
            end
            data.Properties.VariableNames = {'latitud', 'longitud', 'altura'};
        end

        function insertData(~, conn, data)
            for i = 1:height(data)
                latitud = data.latitud(i);
                longitud = data.longitud(i);
                altura = data.altura(i);
                insert(conn, 'coordenadas', {'latitud', 'longitud', 'altura'}, {latitud, longitud, altura});
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
         
        end

        % Button pushed function: ConvertirPTLGeodButton
        function ConvertirPTLGeodButtonPushed(app, event)
 try
            
     % Permitir al usuario seleccionar un archivo
                [fileName, filePath] = uigetfile({'*.txt;*.csv', 'Text and CSV files (*.txt, *.csv)'}, 'Select a file');
                if isequal(fileName, 0)
                    disp('File selection canceled.');
                    return;
                end
                filePath = fullfile(filePath, fileName);

                % Leer datos del archivo
                data = app.leerArchivo(filePath);
     % Conexión a la base de datos
            conn = database('mysql2', 'alexs', 'localhost');
            
            if isopen(conn)
                % Leer datos de la base de datos
                data = fetch(conn, ['SELECT Norte, Este, Altura ' ...
                    'FROM coordenadas_ptl']);
                close(conn);
                
                % Extraer coordenadas
                N = data.Norte;
                E = data.Este;
                altura = data.Altura;

                MeridianoC = -70; % Meridiano central en grados decimales

                % Parámetros PTL
                R = 6378000; % Radio medio
                hptl = 600; % Altura plano PTL
                falsoN = 7000000; % Falso Norte
                falsoE = 200000;  % Falso Este
                Factoresc = (R + hptl) / R; % Factor escala

                % Parámetros elipsoide (WGS84)
                a = 6378137.0; % Eje semi mayor
                f = 1 / 298.257223563; % Achatamiento
                e2 = 2 * f - f^2; % Excentricidad al cuadrado

                % Convertir el meridiano central a radianes
                MeridianoC = deg2rad(MeridianoC);

                % Crear matrices para almacenar los resultados
                latitudes = zeros(size(N));
                longitudes = zeros(size(E));

                % Calcular las coordenadas geodésicas a partir de PTL
                for i = 1:length(N)
                    % Convertir coord. PTL a coord. en sistema de proyección
                    N_proj = (N(i) - falsoN) / Factoresc;
                    E_proj = (E(i) - falsoE) / Factoresc;

                    % Aproximación inicial de la latitud meridional
                    M = N_proj;
                    mu = M / (a * (1 - e2 / 4 - 3 * e2^2 / 64 - 5 * e2^3 / 256));
                    e1 = (1 - sqrt(1 - e2)) / (1 + sqrt(1 - e2));
                    
                    % Serie de Taylor inversa para obtener la latitud geodésica
                    lat1 = mu + (3 * e1 / 2 - 27 * e1^3 / 32) * sin(2 * mu) ...
                        + (21 * e1^2 / 16 - 55 * e1^4 / 32) * sin(4 * mu) ...
                        + (151 * e1^3 / 96) * sin(6 * mu) ...
                        + (1097 * e1^4 / 512) * sin(8 * mu);

                    % Calcular parámetros necesarios
                    N1 = a / sqrt(1 - e2 * sin(lat1)^2);
                    T1 = tan(lat1)^2;
                    C1 = e2 * cos(lat1)^2 / (1 - e2);
                    R1 = a * (1 - e2) / (1 - e2 * sin(lat1)^2)^(3 / 2);
                    D = E_proj / N1;

                    % Calcular la latitud geodésica
                    lat = lat1 - (N1 * tan(lat1) / R1) * (D^2 / 2 - (5 + 3 * T1 + ...
                        10 * C1 - 4 * C1^2 - 9 * e2) * D^4 / 24 ...
                        + (61 + 90 * T1 + 298 * C1 + 45 * T1^2 - 252 * e2 - 3 * ...
                        C1^2) * D^6 / 720);

                    % Calcular la longitud geodésica
                    lon = MeridianoC + (D - (1 + 2 * T1 + C1) * D^3 / 6 ...
                        + (5 - 2 * C1 + 28 * T1 - 3 * C1^2 + 8 * e2 + 24 * T1^2) * ...
                        D^5 / 120) / cos(lat1);

                    % Convertir radianes a grados
                    latitudes(i) = rad2deg(lat);
                    longitudes(i) = rad2deg(lon);
                end

                % Actualizar la tabla con los resultados
                app.UITable.Data = [latitudes, longitudes, altura];
                app.UITable.ColumnName = {'Latitud', 'Longitud', 'Altura'};
                app.UITable.ColumnFormat = {'bank', 'bank', 'bank'};

                % Mostrar mensaje de éxito
                uialert(app.UIFigure, 'Conversión PTL a Geodésicas completada. Los resultados se han actualizado en la tabla.', 'Éxito', 'Icon', 'success');
            else
                % Mostrar mensaje de error si no se puede conectar a la base de datos
                uialert(app.UIFigure, 'Error: No se pudo conectar a la base de datos.', 'Error', 'Icon', 'error');
            end
        catch ME
            % Mostrar mensaje de error si ocurre una excepción
            uialert(app.UIFigure, ['Error: ' ME.message], 'Error', 'Icon', 'error');
 end
        
        
        end

        % Button pushed function: ClculodeazimutButton
        function ClculodeazimutButtonPushed(app, event)
            try
            % Leer las coordenadas PTL desde el archivo generado
            data = 'coordenadas_ptl.txt';
            coords = load(data);
            N = coords(:, 1);
            E = coords(:, 2);

            % Crear una matriz para almacenar los azimuts
            azimuts = zeros(length(N) - 1, 1);

            % Calcular los azimuts entre pares de puntos
            for i = 1:length(N) - 1
                deltaE = E(i+1) - E(i);
                deltaN = N(i+1) - N(i);
                azimut = atan2d(deltaE, deltaN);

                % Asegurar que el azimut esté en el rango [0, 400)
                if azimut < 0
                    azimut = azimut + 400;
                end

                azimuts(i) = azimut;
            end

            % Filtrar los azimuts para mantener solo los impares
            azimutsImpares = azimuts(1:2:end);

            % Mostrar los resultados en la tabla
            app.UITable.Data = [azimutsImpares, NaN(size(azimutsImpares))];
            app.UITable.ColumnName = {'Azimut', ''};
            app.UITable.ColumnFormat = {'bank', 'numeric'};
            
            % Mostrar mensaje de éxito
            uialert(app.UIFigure, 'Cálculo de azimuts completado. Los resultados se han actualizado en la tabla.', 'Éxito', 'Icon', 'success');
            
        catch ME
            % Mostrar mensaje de error si ocurre una excepción
            uialert(app.UIFigure, ['Error: ' ME.message], 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: CorreccinDistanciaButton
        function CorreccinDistanciaButtonPushed(app, event)
             try
            % Leer las coordenadas PTL desde el archivo generado
            data = 'coordenadas_ptl.txt';
            coords = load(data);
            N = coords(:, 1);
            E = coords(:, 2);
            h = coords(:, 3);
            hptl = 600;
            R = 6378000;

            % Inicializar las matrices para almacenar los resultados
            DPtl = zeros(length(N)-1, 1);
            Cm = zeros(length(N)-1, 1);
            difS = zeros(length(N)-1, 1);
            Dhm = zeros(length(N)-1, 1);

            % Calcular las ecuaciones para cada par de coordenadas
            for i = 1:length(N) - 1
                DPtl(i) = sqrt((N(i+1)-N(i)).^2 + (E(i+1)-E(i)).^2); % Distancia plana PTL
                Cm(i) = ((h(i)+h(i+1))/2) - hptl; % Cota media respecto PTL
                difS(i) = (DPtl(i) * Cm(i)) / R; % Corrección
                Dhm(i) = DPtl(i) + difS(i); % Distancia horizontal
            end

            % Crear una tabla para almacenar los resultados
            resultados = table(DPtl, Cm, difS, Dhm, ...
                               'VariableNames', {'DPtl', 'Cm', 'difS', 'Dhm'});

            % Eliminar las filas pares
            esPar = mod(1:height(resultados), 2) == 0;
            resultados(esPar, :) = [];

            % Mostrar los resultados en la tabla
            app.UITable.Data = [resultados.DPtl, resultados.Cm, resultados.difS, resultados.Dhm];
            app.UITable.ColumnName = {'DPtl', 'Cm', 'difS', 'Dhm'};
            app.UITable.ColumnFormat = {'bank', 'bank', 'bank', 'bank'};
            
            % Mostrar mensaje de éxito
            uialert(app.UIFigure, 'Cálculo de corrección de distancia PTL completado. Los resultados se han actualizado en la tabla.', 'Éxito', 'Icon', 'success');

        catch ME
            % Mostrar mensaje de error si ocurre una excepción
            uialert(app.UIFigure, ['Error: ' ME.message], 'Error', 'Icon', 'error');
             end             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvertirGeoPTLButton = uibutton(app.UIFigure, 'push');
            app.ConvertirGeoPTLButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirGeoPTLButtonPushed, true);
            app.ConvertirGeoPTLButton.Text = 'Convertir Geo-PTL';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Norte'; 'Este'; 'Altura'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ConversindecoordenadasGeodsicasPTLLabel = uilabel(app.UIFigure);
            app.ConversindecoordenadasGeodsicasPTLLabel.Text = 'Conversión de coordenadas Geodésicas - PTL';
            app.ConvertirPTLGeodButton = uibutton(app.UIFigure, 'push');
            app.ConvertirPTLGeodButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirPTLGeodButtonPushed, true);
            app.ConvertirPTLGeodButton.Text = 'Convertir PTL-Geod';
            app.ClculodeazimutButton = uibutton(app.UIFigure, 'push');
            app.ClculodeazimutButton.ButtonPushedFcn = createCallbackFcn(app, @ClculodeazimutButtonPushed, true);
            app.ClculodeazimutButton.Text = 'Cálculo de azimut';
            app.CorreccinDistanciaButton = uibutton(app.UIFigure, 'push');
            app.CorreccinDistanciaButton.ButtonPushedFcn = createCallbackFcn(app, @CorreccinDistanciaButtonPushed, true);
            app.CorreccinDistanciaButton.Text = 'Corrección Distancia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end